classdef DensoSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Joint_1SliderLabel         matlab.ui.control.Label
        Joint_1Slider              matlab.ui.control.Slider
        Joint_2SliderLabel         matlab.ui.control.Label
        Joint_2Slider              matlab.ui.control.Slider
        Joint_3SliderLabel         matlab.ui.control.Label
        Joint_3Slider              matlab.ui.control.Slider
        Joint_4SliderLabel         matlab.ui.control.Label
        Joint_4Slider              matlab.ui.control.Slider
        Joint_5SliderLabel         matlab.ui.control.Label
        Joint_5Slider              matlab.ui.control.Slider
        Joint_6SliderLabel         matlab.ui.control.Label
        Joint_6Slider              matlab.ui.control.Slider
        q1EditFieldLabel           matlab.ui.control.Label
        q1EditField                matlab.ui.control.EditField
        q2EditFieldLabel           matlab.ui.control.Label
        q2EditField                matlab.ui.control.EditField
        q3EditFieldLabel           matlab.ui.control.Label
        q3EditField                matlab.ui.control.EditField
        q4EditFieldLabel           matlab.ui.control.Label
        q4EditField                matlab.ui.control.EditField
        q5EditFieldLabel           matlab.ui.control.Label
        q5EditField                matlab.ui.control.EditField
        q6EditFieldLabel           matlab.ui.control.Label
        q6EditField                matlab.ui.control.EditField
        DensoVS060Label            matlab.ui.control.Label
        xEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.EditField
        yEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.EditField
        zEditFieldLabel            matlab.ui.control.Label
        zEditField                 matlab.ui.control.EditField
        rEditFieldLabel            matlab.ui.control.Label
        rEditField                 matlab.ui.control.EditField
        pEditFieldLabel            matlab.ui.control.Label
        pEditField                 matlab.ui.control.EditField
        y_EditFieldLabel           matlab.ui.control.Label
        y_EditField                matlab.ui.control.EditField
        InverseKinematicsLabel     matlab.ui.control.Label
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.EditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.EditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.EditField
        ApplyButton                matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        PlottheEnvironmentButton   matlab.ui.control.Button
        RunDemoButton              matlab.ui.control.Button
        GamePadButton              matlab.ui.control.Button
        eStopButton                matlab.ui.control.Button
        eStopstatusEditFieldLabel  matlab.ui.control.Label
        eStopstatusEditField       matlab.ui.control.EditField
        LightCurtainButton         matlab.ui.control.Button
        ColorEditFieldLabel        matlab.ui.control.Label
        ColorEditField             matlab.ui.control.EditField
        ColorLampLabel             matlab.ui.control.Label
        ColorLamp                  matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        denso;
        denso2;
        boxes;
        conveyor;
        resumeSign = 0;
        btnState =0;
        lightCurtain;
        lightCurtainFlag=false;
    end
    
    methods (Access = public)
%         function results = getEstopState(app)
%             value = app.eStopSwitch.Value;
%             if strcmp(value,'On')
%                 results = 1;
%             else
%                 results = 0;
%             end
%         end
        
        function qJoints = GetJointState(app)
            q1 = deg2rad(app.Joint_1Slider.Value);
            q2 = deg2rad(app.Joint_2Slider.Value);
            q3 = deg2rad(app.Joint_3Slider.Value);
            q4 = deg2rad(app.Joint_4Slider.Value);
            q5 = deg2rad(app.Joint_5Slider.Value);
            q6 = deg2rad(app.Joint_6Slider.Value);
            qJoints = [q1,q2,q3,q4,q5,q6];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: q1EditField
        function q1EditFieldValueChanged(app, event)
            app.Joint_1Slider.Value = str2double(app.q1EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changed function: Joint_1Slider
        function Joint_1SliderValueChanged(app, event)
            app.q1EditField.Value = num2str(round(app.Joint_1Slider.Value,0));
        end

        % Value changed function: Joint_2Slider
        function Joint_2SliderValueChanged(app, event)
            app.q2EditField.Value = num2str(round(app.Joint_2Slider.Value,0));
        end

        % Value changed function: q2EditField
        function q2EditFieldValueChanged(app, event)
            app.Joint_2Slider.Value = str2double(app.q2EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changed function: Joint_3Slider
        function Joint_3SliderValueChanged(app, event)
            app.q3EditField.Value = num2str(round(app.Joint_3Slider.Value,0)); 
        end

        % Value changed function: Joint_4Slider
        function Joint_4SliderValueChanged(app, event)
            app.q4EditField.Value = num2str(round(app.Joint_4Slider.Value,0));
        end

        % Value changed function: Joint_5Slider
        function Joint_5SliderValueChanged(app, event)
            app.q5EditField.Value = num2str(round(app.Joint_5Slider.Value,0));
        end

        % Value changed function: Joint_6Slider
        function Joint_6SliderValueChanged(app, event)
            app.q6EditField.Value = num2str(round(app.Joint_6Slider.Value,0));    
        end

        % Value changed function: q3EditField
        function q3EditFieldValueChanged(app, event)
            app.Joint_3Slider.Value = str2double(app.q3EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changed function: q4EditField
        function q4EditFieldValueChanged(app, event)
            app.Joint_4Slider.Value = str2double(app.q4EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changed function: q5EditField
        function q5EditFieldValueChanged(app, event)
            app.Joint_5Slider.Value = str2double(app.q5EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changed function: q6EditField
        function q6EditFieldValueChanged(app, event)
            app.Joint_6Slider.Value = str2double(app.q6EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Callback function
        function UIAxesButtonDown(app, event)
%             robot = DensoVS060(false,transl(0,0,0),'DensoVS060');
%             q = app.GetJointState;
%             robot.model.animate(q);
        end

        % Value changing function: Joint_1Slider
        function Joint_1SliderValueChanging(app, event)
            app.q1EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changing function: Joint_2Slider
        function Joint_2SliderValueChanging(app, event)
            app.q2EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changing function: Joint_3Slider
        function Joint_3SliderValueChanging(app, event)
            app.q3EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changing function: Joint_4Slider
        function Joint_4SliderValueChanging(app, event)
            app.q4EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changing function: Joint_5Slider
        function Joint_5SliderValueChanging(app, event)
            app.q5EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Value changing function: Joint_6Slider
        function Joint_6SliderValueChanging(app, event)
            app.q6EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            x = str2double(app.XEditField.Value);
            y = str2double(app.YEditField.Value);
            z = str2double(app.ZEditField.Value);
            rotM = rotx(-180,'deg');
            pose = rt2tr(rotM,[x;y;z]);
            qInverse = app.denso.IKine(pose);
%             app.Joint_1Slider.Value = round(qInverse(1),0);
%             app.Joint_2Slider.Value = round(qInverse(2),0);
%             app.Joint_3Slider.Value = round(qInverse(3),0);
%             app.Joint_4Slider.Value = round(qInverse(4),0);
%             app.Joint_5Slider.Value = round(qInverse(5),0);
%             app.Joint_6Slider.Value = round(qInverse(6),0);
            app.denso.model.animate(qInverse);
            app.q1EditField.Value = num2str(round(rad2deg(qInverse(1)),4));
            app.q2EditField.Value = num2str(round(rad2deg(qInverse(2)),4));
            app.q3EditField.Value = num2str(round(rad2deg(qInverse(3)),4));
            app.q4EditField.Value = num2str(round(rad2deg(qInverse(4)),4));
            app.q5EditField.Value = num2str(round(rad2deg(qInverse(5)),4));
            app.q6EditField.Value = num2str(round(rad2deg(qInverse(6)),4));
            
            app.Joint_1Slider.Value = str2double(app.q1EditField.Value);
            app.Joint_2Slider.Value = str2double(app.q2EditField.Value);
            app.Joint_3Slider.Value = str2double(app.q3EditField.Value);
            app.Joint_4Slider.Value = str2double(app.q4EditField.Value);
            app.Joint_5Slider.Value = str2double(app.q5EditField.Value);
            app.Joint_6Slider.Value = str2double(app.q6EditField.Value);
            
            app.xEditField.Value = num2str(round(x,4));
            app.yEditField.Value = num2str(round(y,4));
            app.zEditField.Value = num2str(round(z,4));
            RPY = tr2rpy(pose,'deg');
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
        end

        % Button pushed function: PlottheEnvironmentButton
        function PlottheEnvironmentButtonPushed(app, event)
            [app.denso,app.denso2,app.boxes,app.lightCurtain,app.conveyor] = EnvironmentSetup;
        end

        % Button pushed function: RunDemoButton
        function RunDemoButtonPushed(app, event)
            app.Lamp.Color = 'g';
            app.btnState =0;
%             app.eStopstatusEditField.Value = num2str(app.btnState);
            app.eStopstatusEditField.Value = 'Working';
            NewMain(app.denso,app.denso2,app.boxes,app,app.conveyor,app.lightCurtain);
        end

        % Button pushed function: GamePadButton
        function GamePadButtonPushed(app, event)
            GamepadController(app.denso,app)
        end

        % Button pushed function: eStopButton
        function eStopButtonPushed(app, event)
            if app.btnState ==0
                app.btnState =1;
                app.Lamp.Color = 'r';
            elseif app.btnState ==1
                app.btnState =2;
                app.Lamp.Color = 'r';
            elseif app.btnState ==2
                app.btnState =0;
                app.Lamp.Color = 'g';
            end
            
%             app.eStopstatusEditField.Value = num2str(app.btnState);
            switch app.btnState
                case 0
                    app.eStopstatusEditField.Value = 'Working';
                case 1
                    app.eStopstatusEditField.Value = 'Stopped';
                case 2
                    app.eStopstatusEditField.Value = 'Pending';
                otherwise
                    app.eStopstatusEditField.Value = ['error ',num2str(app.btnState)];
            end
            
            app.denso.eStopState = app.btnState;
            app.denso2.eStopState = app.btnState;
            app.boxes{1}.eStopState = app.btnState;
            app.boxes{2}.eStopState = app.btnState;
            app.boxes{3}.eStopState = app.btnState;
        end

        % Value changed function: eStopstatusEditField
        function eStopstatusEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: LightCurtainButton
        function LightCurtainButtonPushed(app, event)
              LCTesting(app.lightCurtain,app.denso);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint_1SliderLabel = uilabel(app.UIFigure);
            app.Joint_1SliderLabel.Text = 'Joint_1';
            app.Joint_1Slider = uislider(app.UIFigure);
            app.Joint_1Slider.Limits = [-170 170];
            app.Joint_1Slider.MajorTicks = [-170 -85 0 85 170];
            app.Joint_1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_1SliderValueChanged, true);
            app.Joint_1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_1SliderValueChanging, true);
            app.Joint_1Slider.MinorTicks = [];
            app.Joint_2SliderLabel = uilabel(app.UIFigure);
            app.Joint_2SliderLabel.Text = 'Joint_2';
            app.Joint_2Slider = uislider(app.UIFigure);
            app.Joint_2Slider.Limits = [-120 120];
            app.Joint_2Slider.MajorTicks = [-120 -60 0 60 120];
            app.Joint_2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_2SliderValueChanged, true);
            app.Joint_2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_2SliderValueChanging, true);
            app.Joint_2Slider.MinorTicks = [];
            app.Joint_3SliderLabel = uilabel(app.UIFigure);
            app.Joint_3SliderLabel.Text = 'Joint_3';
            app.Joint_3Slider = uislider(app.UIFigure);
            app.Joint_3Slider.Limits = [-125 155];
            app.Joint_3Slider.MajorTicks = [-125 -63 0 78 155];
            app.Joint_3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_3SliderValueChanged, true);
            app.Joint_3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_3SliderValueChanging, true);
            app.Joint_3Slider.MinorTicks = [];
            app.Joint_4SliderLabel = uilabel(app.UIFigure);
            app.Joint_4SliderLabel.Text = 'Joint_4';
            app.Joint_4Slider = uislider(app.UIFigure);
            app.Joint_4Slider.Limits = [-270 270];
            app.Joint_4Slider.MajorTicks = [-270 -135 0 135 270];
            app.Joint_4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_4SliderValueChanged, true);
            app.Joint_4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_4SliderValueChanging, true);
            app.Joint_4Slider.MinorTicks = [];
            app.Joint_5SliderLabel = uilabel(app.UIFigure);
            app.Joint_5SliderLabel.Text = 'Joint_5';
            app.Joint_5Slider = uislider(app.UIFigure);
            app.Joint_5Slider.Limits = [-120 120];
            app.Joint_5Slider.MajorTicks = [-120 -60 0 60 120];
            app.Joint_5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_5SliderValueChanged, true);
            app.Joint_5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_5SliderValueChanging, true);
            app.Joint_5Slider.MinorTicks = [];
            app.Joint_6SliderLabel = uilabel(app.UIFigure);
            app.Joint_6SliderLabel.Text = 'Joint_6';
            app.Joint_6Slider = uislider(app.UIFigure);
            app.Joint_6Slider.Limits = [-360 360];
            app.Joint_6Slider.MajorTicks = [-360 -180 0 180 360];
            app.Joint_6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_6SliderValueChanged, true);
            app.Joint_6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_6SliderValueChanging, true);
            app.Joint_6Slider.MinorTicks = [];
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'text');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q1EditField.Value = '0';
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'text');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @q2EditFieldValueChanged, true);
            app.q2EditField.Value = '0';
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'text');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @q3EditFieldValueChanged, true);
            app.q3EditField.Value = '0';
            app.q4EditFieldLabel = uilabel(app.UIFigure);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.UIFigure, 'text');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @q4EditFieldValueChanged, true);
            app.q4EditField.Value = '0';
            app.q5EditFieldLabel = uilabel(app.UIFigure);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.UIFigure, 'text');
            app.q5EditField.ValueChangedFcn = createCallbackFcn(app, @q5EditFieldValueChanged, true);
            app.q5EditField.Value = '0';
            app.q6EditFieldLabel = uilabel(app.UIFigure);
            app.q6EditFieldLabel.Text = {'q6'; ''};
            app.q6EditField = uieditfield(app.UIFigure, 'text');
            app.q6EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.q6EditField.Value = '0';
            app.DensoVS060Label = uilabel(app.UIFigure);
            app.DensoVS060Label.Text = 'DensoVS060';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.xEditField.Value = '0';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.yEditField.Value = '0';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            app.zEditField.Value = '0';
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'text');
            app.rEditField.Value = '0';
            app.pEditFieldLabel = uilabel(app.UIFigure);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.UIFigure, 'text');
            app.pEditField.Value = '0';
            app.y_EditFieldLabel = uilabel(app.UIFigure);
            app.y_EditFieldLabel.Text = 'y_';
            app.y_EditField = uieditfield(app.UIFigure, 'text');
            app.y_EditField.Value = '0';
            app.InverseKinematicsLabel = uilabel(app.UIFigure);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Lamp = uilamp(app.UIFigure);
            app.PlottheEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.PlottheEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @PlottheEnvironmentButtonPushed, true);
            app.PlottheEnvironmentButton.Text = 'Plot the Environment';
            app.RunDemoButton = uibutton(app.UIFigure, 'push');
            app.RunDemoButton.ButtonPushedFcn = createCallbackFcn(app, @RunDemoButtonPushed, true);
            app.RunDemoButton.Text = 'Run Demo';
            app.GamePadButton = uibutton(app.UIFigure, 'push');
            app.GamePadButton.ButtonPushedFcn = createCallbackFcn(app, @GamePadButtonPushed, true);
            app.GamePadButton.Text = 'GamePad';
            app.eStopButton = uibutton(app.UIFigure, 'push');
            app.eStopButton.ButtonPushedFcn = createCallbackFcn(app, @eStopButtonPushed, true);
            app.eStopButton.Text = 'eStop';
            app.eStopstatusEditFieldLabel = uilabel(app.UIFigure);
            app.eStopstatusEditFieldLabel.Text = 'eStop status';
            app.eStopstatusEditField = uieditfield(app.UIFigure, 'text');
            app.eStopstatusEditField.ValueChangedFcn = createCallbackFcn(app, @eStopstatusEditFieldValueChanged, true);
            app.LightCurtainButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainButton.ButtonPushedFcn = createCallbackFcn(app, @LightCurtainButtonPushed, true);
            app.LightCurtainButton.Text = 'Light Curtain';
            app.ColorEditFieldLabel = uilabel(app.UIFigure);
            app.ColorEditFieldLabel.Text = 'Color';
            app.ColorEditField = uieditfield(app.UIFigure, 'text');
            app.ColorLampLabel = uilabel(app.UIFigure);
            app.ColorLampLabel.Text = 'Color';
            app.ColorLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DensoSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end