classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ControlPanel              matlab.ui.container.Panel
        RunDemoButton             matlab.ui.control.Button
        eStopSwitchLabel          matlab.ui.control.Label
        eStopSwitch               matlab.ui.control.ToggleSwitch
        Lamp                      matlab.ui.control.Lamp
        PlotTheEnvironmentButton  matlab.ui.control.Button
        ResumeTaskButton          matlab.ui.control.Button
        JogRobotJointsPanel       matlab.ui.container.Panel
        Joint1SliderLabel         matlab.ui.control.Label
        Joint1Slider              matlab.ui.control.Slider
        EditField                 matlab.ui.control.NumericEditField
        Joint2SliderLabel         matlab.ui.control.Label
        Joint2Slider              matlab.ui.control.Slider
        Joint3SliderLabel         matlab.ui.control.Label
        Joint3Slider              matlab.ui.control.Slider
        Joint4SliderLabel         matlab.ui.control.Label
        Joint4Slider              matlab.ui.control.Slider
        Joint5SliderLabel         matlab.ui.control.Label
        Joint5Slider              matlab.ui.control.Slider
        Joint6SliderLabel         matlab.ui.control.Label
        Joint6Slider              matlab.ui.control.Slider
        EditField_2               matlab.ui.control.NumericEditField
        EditField_3               matlab.ui.control.NumericEditField
        EditField_4               matlab.ui.control.NumericEditField
        EditField_5               matlab.ui.control.NumericEditField
        EditField_6               matlab.ui.control.NumericEditField
        RollEditFieldLabel        matlab.ui.control.Label
        RollEditField             matlab.ui.control.EditField
        PitchEditFieldLabel       matlab.ui.control.Label
        PitchEditField            matlab.ui.control.EditField
        YallEditFieldLabel        matlab.ui.control.Label
        YallEditField             matlab.ui.control.EditField
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.EditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.EditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.EditField
        CartesanControlPanel      matlab.ui.container.Panel
        XSliderLabel              matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        XEditField_2              matlab.ui.control.NumericEditField
        YSliderLabel              matlab.ui.control.Label
        YSlider                   matlab.ui.control.Slider
        ZSliderLabel              matlab.ui.control.Label
        ZSlider                   matlab.ui.control.Slider
        XEditField_3              matlab.ui.control.NumericEditField
        XEditField_4              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        robot;
        box;
        resumeSign = 0;
    end
    
    methods (Access = public)
        
        function results = getEstopState(app)
            value = app.eStopSwitch.Value;
            if strcmp(value,'On')
                results = 1;
            else
                results = 0;
            end
        end
        
%         function resumeSign = TaskResume(app)
%             if app.ResumeTaskButton.ButtonPushedFcn
%                 resumeSign = 1;
%             else
%                 resumeSign = 0;
%             end
%         end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunDemoButton
        function RunDemoButtonPushed(app, event)
            NewMain(app.robot,app.box,app);
        end

        % Value changed function: eStopSwitch
        function eStopSwitchValueChanged(app, event)
            value = app.eStopSwitch.Value;
            if strcmp(value,'On') 
                app.Lamp.Color = 'r';
            else
                app.Lamp.Color = 'g';
            end
            
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            robot = DensoVS060(false,transl(0,0,0),'denso')
        end

        % Button pushed function: PlotTheEnvironmentButton
        function PlotTheEnvironmentButtonPushed(app, event)
            [app.robot,app.box] = EnvironmentSetup;
        end

        % Button pushed function: ResumeTaskButton
        function ResumeTaskButtonPushed(app, event)
            app.resumeSign = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.RunDemoButton = uibutton(app.ControlPanel, 'push');
            app.RunDemoButton.ButtonPushedFcn = createCallbackFcn(app, @RunDemoButtonPushed, true);
            app.RunDemoButton.Text = 'Run Demo';
            app.eStopSwitchLabel = uilabel(app.ControlPanel);
            app.eStopSwitchLabel.Text = 'eStop';
            app.eStopSwitch = uiswitch(app.ControlPanel, 'toggle');
            app.eStopSwitch.ValueChangedFcn = createCallbackFcn(app, @eStopSwitchValueChanged, true);
            app.Lamp = uilamp(app.ControlPanel);
            app.PlotTheEnvironmentButton = uibutton(app.ControlPanel, 'push');
            app.PlotTheEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTheEnvironmentButtonPushed, true);
            app.PlotTheEnvironmentButton.Text = 'Plot The Environment';
            app.ResumeTaskButton = uibutton(app.ControlPanel, 'push');
            app.ResumeTaskButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeTaskButtonPushed, true);
            app.ResumeTaskButton.Text = 'Resume Task';
            app.JogRobotJointsPanel = uipanel(app.UIFigure);
            app.JogRobotJointsPanel.Title = 'Jog Robot Joints';
            app.Joint1SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.JogRobotJointsPanel);
            app.Joint1Slider.Limits = [-170 170];
            app.Joint1Slider.MajorTicks = [-170 0 170];
            app.Joint1Slider.MinorTicks = [];
            app.EditField = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.Joint2SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.JogRobotJointsPanel);
            app.Joint2Slider.Limits = [-120 120];
            app.Joint2Slider.MajorTicks = [-120 0 120];
            app.Joint2Slider.MinorTicks = [];
            app.Joint3SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.JogRobotJointsPanel);
            app.Joint3Slider.Limits = [-125 155];
            app.Joint3Slider.MajorTicks = [-125 0 155];
            app.Joint3Slider.MinorTicks = [];
            app.Joint4SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.JogRobotJointsPanel);
            app.Joint4Slider.Limits = [-270 270];
            app.Joint4Slider.MajorTicks = [-270 0 270];
            app.Joint4Slider.MinorTicks = [];
            app.Joint5SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.JogRobotJointsPanel);
            app.Joint5Slider.Limits = [-120 120];
            app.Joint5Slider.MajorTicks = [-120 0 120];
            app.Joint5Slider.MinorTicks = [];
            app.Joint6SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.JogRobotJointsPanel);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.MajorTicks = [-360 0 360];
            app.Joint6Slider.MinorTicks = [];
            app.EditField_2 = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.EditField_3 = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.EditField_4 = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.EditField_5 = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.EditField_6 = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.RollEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.PitchEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.YallEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.YallEditFieldLabel.Text = 'Yall';
            app.YallEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.XEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.YEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.ZEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.CartesanControlPanel = uipanel(app.UIFigure);
            app.CartesanControlPanel.Title = 'Cartesan Control';
            app.XSliderLabel = uilabel(app.CartesanControlPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.CartesanControlPanel);
            app.XEditField_2 = uieditfield(app.CartesanControlPanel, 'numeric');
            app.YSliderLabel = uilabel(app.CartesanControlPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.CartesanControlPanel);
            app.ZSliderLabel = uilabel(app.CartesanControlPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.CartesanControlPanel);
            app.XEditField_3 = uieditfield(app.CartesanControlPanel, 'numeric');
            app.XEditField_4 = uieditfield(app.CartesanControlPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end