classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ControlPanel              matlab.ui.container.Panel
        RunDemoButton             matlab.ui.control.Button
        eStopSwitchLabel          matlab.ui.control.Label
        eStopSwitch               matlab.ui.control.ToggleSwitch
        Lamp                      matlab.ui.control.Lamp
        PlotTheEnvironmentButton  matlab.ui.control.Button
        ResumeTaskButton          matlab.ui.control.Button
        JogRobotJointsPanel       matlab.ui.container.Panel
        Joint_1SliderLabel        matlab.ui.control.Label
        Joint_1Slider             matlab.ui.control.Slider
        Joint_2SliderLabel        matlab.ui.control.Label
        Joint_2Slider             matlab.ui.control.Slider
        Joint_3SliderLabel        matlab.ui.control.Label
        Joint_3Slider             matlab.ui.control.Slider
        Joint_4SliderLabel        matlab.ui.control.Label
        Joint_4Slider             matlab.ui.control.Slider
        Joint_5SliderLabel        matlab.ui.control.Label
        Joint_5Slider             matlab.ui.control.Slider
        Joint_6SliderLabel        matlab.ui.control.Label
        Joint_6Slider             matlab.ui.control.Slider
        RollEditFieldLabel        matlab.ui.control.Label
        RollEditField             matlab.ui.control.EditField
        PitchEditFieldLabel       matlab.ui.control.Label
        PitchEditField            matlab.ui.control.EditField
        YallEditFieldLabel        matlab.ui.control.Label
        YallEditField             matlab.ui.control.EditField
        xEditFieldLabel           matlab.ui.control.Label
        xEditField                matlab.ui.control.EditField
        yEditFieldLabel           matlab.ui.control.Label
        yEditField                matlab.ui.control.EditField
        zEditFieldLabel           matlab.ui.control.Label
        zEditField                matlab.ui.control.EditField
        q1EditFieldLabel          matlab.ui.control.Label
        q1EditField               matlab.ui.control.NumericEditField
        q2EditFieldLabel          matlab.ui.control.Label
        q2EditField               matlab.ui.control.NumericEditField
        q3EditFieldLabel          matlab.ui.control.Label
        q3EditField               matlab.ui.control.NumericEditField
        q4EditFieldLabel          matlab.ui.control.Label
        q4EditField               matlab.ui.control.NumericEditField
        q5EditFieldLabel          matlab.ui.control.Label
        q5EditField               matlab.ui.control.NumericEditField
        q6EditFieldLabel          matlab.ui.control.Label
        q6EditField               matlab.ui.control.NumericEditField
        CartesanControlPanel      matlab.ui.container.Panel
        XSliderLabel              matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        YSlider                   matlab.ui.control.Slider
        ZSliderLabel              matlab.ui.control.Label
        ZSlider                   matlab.ui.control.Slider
        X_valueEditField          matlab.ui.control.NumericEditField
        Y_ValueEditField          matlab.ui.control.NumericEditField
        Z_ValueEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        robot;
        box;
        resumeSign = 0;
    end
    
    methods (Access = public)
        
        function results = getEstopState(app)
            value = app.eStopSwitch.Value;
            if strcmp(value,'On')
                results = 1;
            else
                results = 0;
            end
        end
        
        function qJoints = GetJointState(app)
            q1 = deg2rad(app.Joint_1Slider.Value);
            q2 = deg2rad(app.Joint_2Slider.Value);
            q3 = deg2rad(app.Joint_3Slider.Value);
            q4 = deg2rad(app.Joint_4Slider.Value);
            q5 = deg2rad(app.Joint_5Slider.Value);
            q6 = deg2rad(app.Joint_6Slider.Value);
            qJoints = [q1,q2,q3,q4,q5,q6];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunDemoButton
        function RunDemoButtonPushed(app, event)
            NewMain(app.robot,app.box,app);
        end

        % Value changed function: eStopSwitch
        function eStopSwitchValueChanged(app, event)
            value = app.eStopSwitch.Value;
            if strcmp(value,'On') 
                app.Lamp.Color = 'r';
            else
                app.Lamp.Color = 'g';
            end
            
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            robot = DensoVS060(false,transl(0,0,0),'denso')
        end

        % Button pushed function: PlotTheEnvironmentButton
        function PlotTheEnvironmentButtonPushed(app, event)
            [app.robot,app.box] = EnvironmentSetup;
        end

        % Button pushed function: ResumeTaskButton
        function ResumeTaskButtonPushed(app, event)
            app.resumeSign = 1;
        end

        % Value changing function: Joint_1Slider
        function Joint_1SliderValueChanging(app, event)
            event.Value = num2str(round(app.Joint_1Slider.Value,0));
        end

        % Value changed function: q1EditField
        function q1EditFieldValueChanged(app, event)
            app.Joint_1Slider.Value = str2double(app.q1EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
            pose = app.denso.FKine(q);
            RPY = tr2rpy(pose,'deg');
            app.xEditField.Value = num2str(round(pose(1,4),4));
            app.yEditField.Value = num2str(round(pose(2,4),4));
            app.zEditField.Value = num2str(round(pose(3,4),4));
            app.rEditField.Value = num2str(round(RPY(1),4));
            app.pEditField.Value = num2str(round(RPY(2),4));
            app.y_EditField.Value = num2str(round(RPY(3),4));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.RunDemoButton = uibutton(app.ControlPanel, 'push');
            app.RunDemoButton.ButtonPushedFcn = createCallbackFcn(app, @RunDemoButtonPushed, true);
            app.RunDemoButton.Text = 'Run Demo';
            app.eStopSwitchLabel = uilabel(app.ControlPanel);
            app.eStopSwitchLabel.Text = 'eStop';
            app.eStopSwitch = uiswitch(app.ControlPanel, 'toggle');
            app.eStopSwitch.ValueChangedFcn = createCallbackFcn(app, @eStopSwitchValueChanged, true);
            app.Lamp = uilamp(app.ControlPanel);
            app.PlotTheEnvironmentButton = uibutton(app.ControlPanel, 'push');
            app.PlotTheEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTheEnvironmentButtonPushed, true);
            app.PlotTheEnvironmentButton.Text = 'Plot The Environment';
            app.ResumeTaskButton = uibutton(app.ControlPanel, 'push');
            app.ResumeTaskButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeTaskButtonPushed, true);
            app.ResumeTaskButton.Text = 'Resume Task';
            app.JogRobotJointsPanel = uipanel(app.UIFigure);
            app.JogRobotJointsPanel.Title = 'Jog Robot Joints';
            app.Joint_1SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint_1SliderLabel.Text = 'Joint_1';
            app.Joint_1Slider = uislider(app.JogRobotJointsPanel);
            app.Joint_1Slider.Limits = [-170 170];
            app.Joint_1Slider.MajorTicks = [-170 0 170];
            app.Joint_1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_1SliderValueChanging, true);
            app.Joint_1Slider.MinorTicks = [];
            app.Joint_2SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint_2SliderLabel.Text = 'Joint_2';
            app.Joint_2Slider = uislider(app.JogRobotJointsPanel);
            app.Joint_2Slider.Limits = [-120 120];
            app.Joint_2Slider.MajorTicks = [-120 0 120];
            app.Joint_2Slider.MinorTicks = [];
            app.Joint_3SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint_3SliderLabel.Text = 'Joint_3';
            app.Joint_3Slider = uislider(app.JogRobotJointsPanel);
            app.Joint_3Slider.Limits = [-125 155];
            app.Joint_3Slider.MajorTicks = [-125 0 155];
            app.Joint_3Slider.MinorTicks = [];
            app.Joint_4SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint_4SliderLabel.Text = 'Joint_4';
            app.Joint_4Slider = uislider(app.JogRobotJointsPanel);
            app.Joint_4Slider.Limits = [-270 270];
            app.Joint_4Slider.MajorTicks = [-270 0 270];
            app.Joint_4Slider.MinorTicks = [];
            app.Joint_5SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint_5SliderLabel.Text = 'Joint_5';
            app.Joint_5Slider = uislider(app.JogRobotJointsPanel);
            app.Joint_5Slider.Limits = [-120 120];
            app.Joint_5Slider.MajorTicks = [-120 0 120];
            app.Joint_5Slider.MinorTicks = [];
            app.Joint_6SliderLabel = uilabel(app.JogRobotJointsPanel);
            app.Joint_6SliderLabel.Text = 'Joint_6';
            app.Joint_6Slider = uislider(app.JogRobotJointsPanel);
            app.Joint_6Slider.Limits = [-360 360];
            app.Joint_6Slider.MajorTicks = [-360 0 360];
            app.Joint_6Slider.MinorTicks = [];
            app.RollEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.PitchEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.YallEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.YallEditFieldLabel.Text = 'Yall';
            app.YallEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.xEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.yEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.zEditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.JogRobotJointsPanel, 'text');
            app.q1EditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q2EditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.q3EditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.q4EditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.q5EditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.q6EditFieldLabel = uilabel(app.JogRobotJointsPanel);
            app.q6EditFieldLabel.Text = 'q6';
            app.q6EditField = uieditfield(app.JogRobotJointsPanel, 'numeric');
            app.CartesanControlPanel = uipanel(app.UIFigure);
            app.CartesanControlPanel.Title = 'Cartesan Control';
            app.XSliderLabel = uilabel(app.CartesanControlPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.CartesanControlPanel);
            app.YSliderLabel = uilabel(app.CartesanControlPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.CartesanControlPanel);
            app.ZSliderLabel = uilabel(app.CartesanControlPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.CartesanControlPanel);
            app.X_valueEditField = uieditfield(app.CartesanControlPanel, 'numeric');
            app.Y_ValueEditField = uieditfield(app.CartesanControlPanel, 'numeric');
            app.Z_ValueEditField = uieditfield(app.CartesanControlPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end